classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Input                       matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        ChooseplatemapLabel             matlab.ui.control.Label
        ChooseplatemapEditField         matlab.ui.control.EditField
        TabGroup3                       matlab.ui.container.TabGroup
        Plate1Tab                       matlab.ui.container.Tab
        ExampleDataSet1Label            matlab.ui.control.Label
        PathtoImagesEditField           matlab.ui.control.EditField
        ListBox_2                       matlab.ui.control.ListBox
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        PathtoImagesLabel               matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RowsLabel                       matlab.ui.control.Label
        ColumnsLabel                    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        FieldsLabel_2                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        TimepointsLabel_3               matlab.ui.control.Label
        Button_29                       matlab.ui.control.Button
        CheckBox_17                     matlab.ui.control.CheckBox
        MetadataLabel                   matlab.ui.control.Label
        PlateMapLabel_2                 matlab.ui.control.Label
        FilterInputLabel_2              matlab.ui.control.Label
        ImageChannelsLabel              matlab.ui.control.Label
        Button_30                       matlab.ui.control.Button
        Button_31                       matlab.ui.control.Button
        Button_32                       matlab.ui.control.Button
        Tab2_2                          matlab.ui.container.Tab
        Button_27                       matlab.ui.control.Button
        Tab_Spot                        matlab.ui.container.Tab
        Button_AddSpot                  matlab.ui.control.Button
        Tab_Segment                     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        Segment1CellTab                 matlab.ui.container.Tab
        AlgorithmDropDown_2Label        matlab.ui.control.Label
        AlgorithmDropDown_2             matlab.ui.control.DropDown
        InputChannelEditField_2Label    matlab.ui.control.Label
        InputChannelEditField_2         matlab.ui.control.EditField
        SegmentNameEditFieldLabel       matlab.ui.control.Label
        SegmentNameEditField            matlab.ui.control.EditField
        SmoothEditFieldLabel            matlab.ui.control.Label
        SmoothEditField                 matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        MinSizeEditFieldLabel           matlab.ui.control.Label
        MinSizeEditField                matlab.ui.control.EditField
        MaxSizeEditFieldLabel           matlab.ui.control.Label
        MaxSizeEditField                matlab.ui.control.EditField
        InputSeedEditFieldLabel         matlab.ui.control.Label
        InputSeedEditField              matlab.ui.control.EditField
        ParametersLabel                 matlab.ui.control.Label
        DetailsLabel                    matlab.ui.control.Label
        InputChannelsLabel              matlab.ui.control.Label
        ListBox_5                       matlab.ui.control.ListBox
        Button_AddSegment               matlab.ui.control.Button
        SegmentationSettingsLabel       matlab.ui.control.Label
        Tab_Measure                     matlab.ui.container.Tab
        Button_Measure_2                matlab.ui.control.Button
        ProcessingLogTextArea           matlab.ui.control.TextArea
        ProcessingLogTextAreaLabel      matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        ExampleMeasurement1Label        matlab.ui.control.Label
        CheckBox_18                     matlab.ui.control.CheckBox
        ListBox_4                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ChannelstoMeasureLabel          matlab.ui.control.Label
        SegmentstoMeasureLabel          matlab.ui.control.Label
        Tab2_3                          matlab.ui.container.Tab
        PrimaryLabelDropDownLabel       matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        SelectMeasurementsLabel         matlab.ui.control.Label
        Button_34                       matlab.ui.control.Button
        ProgressSlider                  matlab.ui.control.Slider
        Tab_Track                       matlab.ui.container.Tab
        Tab_Plot                        matlab.ui.container.Tab
        Tab_Display                     matlab.ui.container.Tab
        DisplayTabGroup                 matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ImageSelectionLabel             matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        LabelOverlay                    matlab.ui.control.Label
        MetricOverlayLabel              matlab.ui.control.Label
        Slider_9                        matlab.ui.control.Slider
        Button_3                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        ROSLabel                        matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        Slider_11                       matlab.ui.control.Slider
        Button_6                        matlab.ui.control.Button
        HA2XLabel                       matlab.ui.control.Label
        CheckBox_11                     matlab.ui.control.CheckBox
        Slider_12                       matlab.ui.control.Slider
        Button_7                        matlab.ui.control.Button
        DAPILabel_3                     matlab.ui.control.Label
        CheckBox_12                     matlab.ui.control.CheckBox
        Slider_13                       matlab.ui.control.Slider
        Button_8                        matlab.ui.control.Button
        SELabel                         matlab.ui.control.Label
        Button_16                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        RowDropDownLabel                matlab.ui.control.Label
        RowDropDown                     matlab.ui.control.DropDown
        ColumnDropDownLabel             matlab.ui.control.Label
        ColumnDropDown                  matlab.ui.control.DropDown
        FieldDropDownLabel              matlab.ui.control.Label
        FieldDropDown                   matlab.ui.control.DropDown
        TimepointDropDownLabel          matlab.ui.control.Label
        TimepointDropDown               matlab.ui.control.DropDown
        Button_18                       matlab.ui.control.Button
        CheckBox_13                     matlab.ui.control.CheckBox
        Slider_14                       matlab.ui.control.Slider
        Button_19                       matlab.ui.control.Button
        CellLabel                       matlab.ui.control.Label
        CheckBox_15                     matlab.ui.control.CheckBox
        Slider_16                       matlab.ui.control.Slider
        Button_21                       matlab.ui.control.Button
        NucSeedLabel                    matlab.ui.control.Label
        CheckBox_16                     matlab.ui.control.CheckBox
        Slider_17                       matlab.ui.control.Slider
        Button_22                       matlab.ui.control.Button
        NucLabel                        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        PlateDropDownLabel              matlab.ui.control.Label
        PlateDropDown                   matlab.ui.control.DropDown
        SaveCurrentFigureButton         matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        DisplaySettingsLabel            matlab.ui.control.Label
        SegmentationLabel               matlab.ui.control.Label
        MadewithinTorontoLabel          matlab.ui.control.Label
        DanielSniderLabel               matlab.ui.control.Label
        Button_Segment                  matlab.ui.control.Button
        MeasurementsLabel               matlab.ui.control.Label
        TrackingLabel                   matlab.ui.control.Label
        PlottingLabel                   matlab.ui.control.Label
        SpottingLabel                   matlab.ui.control.Label
        InputDataLabel                  matlab.ui.control.Label
        Button_Input                    matlab.ui.control.Button
        Button_Spot                     matlab.ui.control.Button
        Button_Plot                     matlab.ui.control.Button
        Button_Track                    matlab.ui.control.Button
        Button_Measure                  matlab.ui.control.Button
        SingleCellAnalysisWorkflowLabel  matlab.ui.control.Label
        KafriLabLabel                   matlab.ui.control.Label
        Button_Plot_2                   matlab.ui.control.Button
        DisplayLabel                    matlab.ui.control.Label
        InputChannelEditField_2Label_2  matlab.ui.control.Label
    end


    properties (Access = public)
        img % currently displayed image
        image % new currently displayed image
        spotting
        spot
        input_data
        image_names
        log_processing_message
        log_startup_message
        StartupLogTextArea
        segmentation
        segment
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup
            set(0,'DefaultFigureWindowStyle','docked');
            warning('off','images:initSize:adjustingMag');
            addpath(genpath('functions'));
            addpath(genpath('plugins'));
            
            % Initialize variables
            app.img = imread('images/example_cells/r02c02f01p01-ch1sk1fk1fl1.tiff');
            app.spot = {};
            app.spotting = {};
            app.spotting.tabgp = [];
            app.input_data = {};
            app.segment = {};
            app.segmentation = {};
            app.segmentation.tabgp = [];
            
            % Initialize Logs
            app.log_processing_message = @log_processing_message;
            app.log_startup_message = @log_startup_message;            
                        
            % Display startup log
            startup_f = uifigure('Color', [.6 .6 .6], 'Position', [412 243 540 510]);
            app.StartupLogTextArea = uitextarea(startup_f,'Position', [5 5 530 500]);
            msg = sprintf('Initializing.');
            app.log_startup_message(app, msg);
                                    
            % Select tab to display on opening
            app.TabGroup.SelectedTab = app.Tab_Display;
            
            % Initialize Data Input Tab
            app.ChooseplatemapEditField.Value = 'C:\Users\daniel snider\Dropbox\Kafri\Projects\GUI\daniel\Camilla_Plate_Map.xlsx';
            
            BrowseButtonPushed(app,'Update'); % trigger for testing
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Callback function
        function SpotAlgorithmDropDownValueChanged(app, event)
            algo_name = app.SpotAlgorithmDropDown.Value;
            % Delete existing UI components before creating new ones on top
%             if isfield(app.spotting,'fields')
%               for idx=1:length(app.spotting.fields)
%                 delete(app.spotting.fields{idx})
%                 delete(app.spotting.labels{idx})
%               end
%             end
%             % Do nothing for 'Off'
%             if strcmp(app.SpotAlgorithmDropDown.Value, 'Off')
%                return 
%             end
%             % Initialize dynamic GUI according to algo_name
%             eval(['definition_' algo_name]);
%             draw_spot(algo_name, params, app, @createCallbackFcn)
%             %app.spotting.Callback(app, 'Update') % trigger once
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color');
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Segment;
        end

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Input;
        end

        % Button pushed function: Button_Spot
        function Button_SpotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Spot;
        end

        % Button pushed function: Button_Measure
        function Button_MeasurePushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Measure;
        end

        % Button pushed function: Button_Track
        function Button_TrackPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Track;
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Plot;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Update
            [FileName,PathName,FilterIndex] = uigetfile('*');
            if ~FileName
                return
            end
            app.ChooseplatemapEditField.Value = [PathName FileName];
            
            %Parse 
            app.input_data.plates = parse_platemap(app.ChooseplatemapEditField.Value);
            
            % Draw
            draw_input_data(app.input_data.plates, app);
            
            % Parse More
            app.input_data.channel_map = {app.input_data.plates.Ch1; app.input_data.plates.Ch2; app.input_data.plates.Ch3; app.input_data.plates.Ch4};
            app.input_data.unique_channels = unique(app.input_data.channel_map);
            parse_image_names(app);
            
            
            % Initialize Display Tab
            msg = sprintf('Loading Figure Display Tab...');
            app.log_startup_message(app, msg);
            draw_figure(app);
            
            % Initialize Segmentation Tab
            msg = sprintf('Loading Segmentation Tab...');
            app.log_startup_message(app, msg);
            add_segment(app, @createCallbackFcn);
            %app.segment{1}.AlgorithmDropDown.ValueChangedFcn(app, 'Update') % trigger once
            
        end

        % Button pushed function: Button_Measure_2
        function Button_Measure_2Pushed(app, event)
            %% EXECUTE MAIN PROCESSING
            start_processing(app);
            
        end

        % Button pushed function: Button_Plot_2
        function Button_Display_Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Display;
        end

        % Button pushed function: Button_AddSegment
        function Button_AddSegmentPushed(app, event)
            add_segment(app, @createCallbackFcn);
        end

        % Button pushed function: Button_AddSpot
        function Button_AddSpotPushed(app, event)
            add_spot(app, @createCallbackFcn);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Input = uitab(app.TabGroup);
            app.Tab_Input.Title = '                          ';
            app.BrowseButton = uibutton(app.Tab_Input, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseplatemapLabel = uilabel(app.Tab_Input);
            app.ChooseplatemapLabel.Text = 'Choose plate map:';
            app.ChooseplatemapEditField = uieditfield(app.Tab_Input, 'text');
            app.ChooseplatemapEditField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.Tab_Input);
            app.Plate1Tab = uitab(app.TabGroup3);
            app.Plate1Tab.Title = 'Plate 1';
            app.ExampleDataSet1Label = uilabel(app.Plate1Tab);
            app.ExampleDataSet1Label.Text = 'ExampleDataSet1';
            app.PathtoImagesEditField = uieditfield(app.Plate1Tab, 'text');
            app.PathtoImagesEditField.Editable = 'off';
            app.ListBox_2 = uilistbox(app.Plate1Tab);
            app.ListBox_2.Items = {'Ch1: DAPI', 'Ch2: SE', 'Ch3: ROS', 'Ch4: HA2X'};
            app.ListBox_2.Value = 'Ch1: DAPI';
            app.UITable = uitable(app.Plate1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Plate1Tab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PathtoImagesLabel = uilabel(app.Plate1Tab);
            app.PathtoImagesLabel.Text = 'Path to Images:';
            app.EditField = uieditfield(app.Plate1Tab, 'text');
            app.EditField.Value = '1-41';
            app.RowsLabel = uilabel(app.Plate1Tab);
            app.RowsLabel.Text = 'Rows:';
            app.ColumnsLabel = uilabel(app.Plate1Tab);
            app.ColumnsLabel.Text = 'Columns:';
            app.EditField_2 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_2.Value = '1-269';
            app.EditField_3 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_3.Value = '1-269';
            app.FieldsLabel_2 = uilabel(app.Plate1Tab);
            app.FieldsLabel_2.Text = 'Fields:';
            app.EditField_4 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_4.Value = '1-269';
            app.TimepointsLabel_3 = uilabel(app.Plate1Tab);
            app.TimepointsLabel_3.Text = 'Timepoints:';
            app.Button_29 = uibutton(app.Plate1Tab, 'push');
            app.Button_29.Icon = 'question-sign.png';
            app.Button_29.Text = '';
            app.CheckBox_17 = uicheckbox(app.Plate1Tab);
            app.CheckBox_17.Text = '';
            app.CheckBox_17.Value = true;
            app.MetadataLabel = uilabel(app.Plate1Tab);
            app.MetadataLabel.Text = 'Metadata:';
            app.PlateMapLabel_2 = uilabel(app.Plate1Tab);
            app.PlateMapLabel_2.Text = 'Plate Map:';
            app.FilterInputLabel_2 = uilabel(app.Plate1Tab);
            app.FilterInputLabel_2.Text = 'Filter Input:';
            app.ImageChannelsLabel = uilabel(app.Plate1Tab);
            app.ImageChannelsLabel.Text = 'Image Channels';
            app.Button_30 = uibutton(app.Plate1Tab, 'push');
            app.Button_30.Icon = 'question-sign.png';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.Plate1Tab, 'push');
            app.Button_31.Icon = 'question-sign.png';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.Plate1Tab, 'push');
            app.Button_32.Icon = 'question-sign.png';
            app.Button_32.Text = '';
            app.Tab2_2 = uitab(app.TabGroup3);
            app.Tab2_2.Title = 'Tab2';
            app.Button_27 = uibutton(app.Tab_Input, 'push');
            app.Button_27.Icon = 'question-sign.png';
            app.Button_27.Text = '';
            app.Tab_Spot = uitab(app.TabGroup);
            app.Tab_Spot.Title = '                          ';
            app.Button_AddSpot = uibutton(app.Tab_Spot, 'push');
            app.Button_AddSpot.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSpotPushed, true);
            app.Button_AddSpot.Text = '➕ Add Spot';
            app.Tab_Segment = uitab(app.TabGroup);
            app.Tab_Segment.Title = '                          ';
            app.TabGroup5 = uitabgroup(app.Tab_Segment);
            app.Segment1CellTab = uitab(app.TabGroup5);
            app.Segment1CellTab.Title = 'Segment 1: Cell';
            app.AlgorithmDropDown_2Label = uilabel(app.Segment1CellTab);
            app.AlgorithmDropDown_2Label.Text = 'Algorithm';
            app.AlgorithmDropDown_2 = uidropdown(app.Segment1CellTab);
            app.AlgorithmDropDown_2.Items = {};
            app.AlgorithmDropDown_2.Value = {};
            app.InputChannelEditField_2Label = uilabel(app.Segment1CellTab);
            app.InputChannelEditField_2Label.Text = 'Input Channel';
            app.InputChannelEditField_2 = uieditfield(app.Segment1CellTab, 'text');
            app.InputChannelEditField_2.Value = 'Cell';
            app.SegmentNameEditFieldLabel = uilabel(app.Segment1CellTab);
            app.SegmentNameEditFieldLabel.Text = 'Segment Name';
            app.SegmentNameEditField = uieditfield(app.Segment1CellTab, 'text');
            app.SegmentNameEditField.Value = 'Cell';
            app.SmoothEditFieldLabel = uilabel(app.Segment1CellTab);
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.Segment1CellTab, 'text');
            app.SmoothEditField.Value = '0';
            app.ThresholdEditFieldLabel = uilabel(app.Segment1CellTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Segment1CellTab, 'text');
            app.ThresholdEditField.Value = '0';
            app.MinSizeEditFieldLabel = uilabel(app.Segment1CellTab);
            app.MinSizeEditFieldLabel.Text = 'Min Size';
            app.MinSizeEditField = uieditfield(app.Segment1CellTab, 'text');
            app.MinSizeEditField.Value = '0';
            app.MaxSizeEditFieldLabel = uilabel(app.Segment1CellTab);
            app.MaxSizeEditFieldLabel.Text = 'Max Size';
            app.MaxSizeEditField = uieditfield(app.Segment1CellTab, 'text');
            app.MaxSizeEditField.Value = '0';
            app.InputSeedEditFieldLabel = uilabel(app.Segment1CellTab);
            app.InputSeedEditFieldLabel.Text = 'Input Seed';
            app.InputSeedEditField = uieditfield(app.Segment1CellTab, 'text');
            app.InputSeedEditField.Value = 'Nucleus';
            app.ParametersLabel = uilabel(app.Segment1CellTab);
            app.ParametersLabel.Text = 'Parameters';
            app.DetailsLabel = uilabel(app.Segment1CellTab);
            app.DetailsLabel.Text = 'Details';
            app.InputChannelsLabel = uilabel(app.Segment1CellTab);
            app.InputChannelsLabel.Text = 'Input Channels';
            app.ListBox_5 = uilistbox(app.Segment1CellTab);
            app.ListBox_5.Items = {'Ch1: DAPI', 'Ch2: SE', 'Ch3: ROS', 'Ch4: HA2X'};
            app.ListBox_5.Multiselect = 'on';
            app.ListBox_5.Value = {'Ch1: DAPI'};
            app.Button_AddSegment = uibutton(app.Tab_Segment, 'push');
            app.Button_AddSegment.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSegmentPushed, true);
            app.Button_AddSegment.Text = '➕ Add Segment';
            app.SegmentationSettingsLabel = uilabel(app.Tab_Segment);
            app.SegmentationSettingsLabel.Text = 'Segmentation Settings';
            app.Tab_Measure = uitab(app.TabGroup);
            app.Tab_Measure.Title = '                          ';
            app.Button_Measure_2 = uibutton(app.Tab_Measure, 'push');
            app.Button_Measure_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Measure_2Pushed, true);
            app.Button_Measure_2.Icon = 'play-button.png';
            app.Button_Measure_2.Text = 'Start Processing';
            app.ProcessingLogTextArea = uitextarea(app.Tab_Measure);
            app.ProcessingLogTextAreaLabel = uilabel(app.Tab_Measure);
            app.ProcessingLogTextAreaLabel.Text = 'Processing Log';
            app.TabGroup4 = uitabgroup(app.Tab_Measure);
            app.Tab_2 = uitab(app.TabGroup4);
            app.Tab_2.Title = 'Tab';
            app.ExampleMeasurement1Label = uilabel(app.Tab_2);
            app.ExampleMeasurement1Label.Text = 'ExampleMeasurement1';
            app.CheckBox_18 = uicheckbox(app.Tab_2);
            app.CheckBox_18.Text = '';
            app.CheckBox_18.Value = true;
            app.ListBox_4 = uilistbox(app.Tab_2);
            app.ListBox_4.Items = {'Ch1: DAPI', 'Ch2: SE', 'Ch3: ROS', 'Ch4: HA2X'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Ch1: DAPI'};
            app.ListBox_3 = uilistbox(app.Tab_2);
            app.ListBox_3.Items = {'Nucleus', 'Nucleolus', 'Cell', 'Focci'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Nucleus'};
            app.ChannelstoMeasureLabel = uilabel(app.Tab_2);
            app.ChannelstoMeasureLabel.Text = 'Channels to Measure:';
            app.SegmentstoMeasureLabel = uilabel(app.Tab_2);
            app.SegmentstoMeasureLabel.Text = 'Segments to Measure:';
            app.Tab2_3 = uitab(app.TabGroup4);
            app.Tab2_3.Title = 'Tab2';
            app.PrimaryLabelDropDownLabel = uilabel(app.Tab_Measure);
            app.PrimaryLabelDropDownLabel.Text = 'Primary Segment:';
            app.DropDown = uidropdown(app.Tab_Measure);
            app.DropDown.Items = {'Cell', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'Cell';
            app.SelectMeasurementsLabel = uilabel(app.Tab_Measure);
            app.SelectMeasurementsLabel.Text = 'Select Measurements';
            app.Button_34 = uibutton(app.Tab_Measure, 'push');
            app.Button_34.Icon = 'question-sign.png';
            app.Button_34.Text = '';
            app.ProgressSlider = uislider(app.Tab_Measure);
            app.ProgressSlider.Limits = [0 1];
            app.ProgressSlider.MajorTicks = [];
            app.ProgressSlider.MajorTickLabels = {};
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.Enable = 'off';
            app.Tab_Track = uitab(app.TabGroup);
            app.Tab_Track.Title = '                          ';
            app.Tab_Plot = uitab(app.TabGroup);
            app.Tab_Plot.Title = '                          ';
            app.Tab_Display = uitab(app.TabGroup);
            app.Tab_Display.Title = '                          ';
            app.DisplayTabGroup = uitabgroup(app.Tab_Display);
            app.Tab = uitab(app.DisplayTabGroup);
            app.Tab.Title = 'Tab';
            app.ImageSelectionLabel = uilabel(app.Tab);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Tab);
            app.ChannelsLabel.Text = 'Channels';
            app.CheckBox_9 = uicheckbox(app.Tab);
            app.CheckBox_9.Text = '';
            app.LabelOverlay = uilabel(app.Tab);
            app.LabelOverlay.Text = 'Label Overlay';
            app.MetricOverlayLabel = uilabel(app.Tab);
            app.MetricOverlayLabel.Text = 'Metric Overlay';
            app.Slider_9 = uislider(app.Tab);
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MajorTickLabels = {};
            app.Slider_9.MinorTicks = [];
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.Text = '🎨';
            app.ROSLabel = uilabel(app.Tab);
            app.ROSLabel.Text = 'ROS';
            app.CheckBox_10 = uicheckbox(app.Tab);
            app.CheckBox_10.Text = '';
            app.Slider_11 = uislider(app.Tab);
            app.Slider_11.MajorTicks = [];
            app.Slider_11.MajorTickLabels = {};
            app.Slider_11.MinorTicks = [];
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.Text = '🎨';
            app.HA2XLabel = uilabel(app.Tab);
            app.HA2XLabel.Text = 'HA2X';
            app.CheckBox_11 = uicheckbox(app.Tab);
            app.CheckBox_11.Text = '';
            app.Slider_12 = uislider(app.Tab);
            app.Slider_12.MajorTicks = [];
            app.Slider_12.MajorTickLabels = {};
            app.Slider_12.MinorTicks = [];
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.Text = '🎨';
            app.DAPILabel_3 = uilabel(app.Tab);
            app.DAPILabel_3.Text = 'DAPI';
            app.CheckBox_12 = uicheckbox(app.Tab);
            app.CheckBox_12.Text = '';
            app.Slider_13 = uislider(app.Tab);
            app.Slider_13.MajorTicks = [];
            app.Slider_13.MajorTickLabels = {};
            app.Slider_13.MinorTicks = [];
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.Text = '🎨';
            app.SELabel = uilabel(app.Tab);
            app.SELabel.Text = 'SE';
            app.Button_16 = uibutton(app.Tab, 'push');
            app.Button_16.Icon = '010-check.png';
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.Tab, 'push');
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '🏿';
            app.RowDropDownLabel = uilabel(app.Tab);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.Tab);
            app.RowDropDown.Editable = 'on';
            app.ColumnDropDownLabel = uilabel(app.Tab);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.Tab);
            app.FieldDropDownLabel = uilabel(app.Tab);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.Tab);
            app.TimepointDropDownLabel = uilabel(app.Tab);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.TimepointDropDown = uidropdown(app.Tab);
            app.Button_18 = uibutton(app.Tab, 'push');
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '📏';
            app.CheckBox_13 = uicheckbox(app.Tab);
            app.CheckBox_13.Text = '';
            app.Slider_14 = uislider(app.Tab);
            app.Slider_14.MajorTicks = [];
            app.Slider_14.MajorTickLabels = {};
            app.Slider_14.MinorTicks = [];
            app.Button_19 = uibutton(app.Tab, 'push');
            app.Button_19.Text = '🎨';
            app.CellLabel = uilabel(app.Tab);
            app.CellLabel.Text = 'Cell Label';
            app.CheckBox_15 = uicheckbox(app.Tab);
            app.CheckBox_15.Text = '';
            app.Slider_16 = uislider(app.Tab);
            app.Slider_16.MajorTicks = [];
            app.Slider_16.MajorTickLabels = {};
            app.Slider_16.MinorTicks = [];
            app.Button_21 = uibutton(app.Tab, 'push');
            app.Button_21.Text = '🎨';
            app.NucSeedLabel = uilabel(app.Tab);
            app.NucSeedLabel.Text = 'Nuc Seed';
            app.CheckBox_16 = uicheckbox(app.Tab);
            app.CheckBox_16.Text = '';
            app.Slider_17 = uislider(app.Tab);
            app.Slider_17.MajorTicks = [];
            app.Slider_17.MajorTickLabels = {};
            app.Slider_17.MinorTicks = [];
            app.Button_22 = uibutton(app.Tab, 'push');
            app.Button_22.Text = '🎨';
            app.NucLabel = uilabel(app.Tab);
            app.NucLabel.Text = 'Nuc Label';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'Eccentricity', 'Area', 'Total Intensity', 'Mean Intensity'};
            app.ListBox.Value = 'Eccentricity';
            app.ExperimentDropDownLabel = uilabel(app.Tab);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Tab);
            app.PlateDropDownLabel = uilabel(app.Tab);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.Tab);
            app.SaveCurrentFigureButton = uibutton(app.Tab, 'push');
            app.SaveCurrentFigureButton.Text = 'Save Current Figure';
            app.Tab2 = uitab(app.DisplayTabGroup);
            app.Tab2.Title = 'Tab2';
            app.DisplaySettingsLabel = uilabel(app.Tab_Display);
            app.DisplaySettingsLabel.Text = 'Display Settings';
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with 💛 in Toronto';
            app.DanielSniderLabel = uilabel(app.UIFigure);
            app.DanielSniderLabel.Text = '@DanielSnider ツ';
            app.Button_Segment = uibutton(app.UIFigure, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Text = '🏿';
            app.MeasurementsLabel = uilabel(app.UIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.TrackingLabel = uilabel(app.UIFigure);
            app.TrackingLabel.Text = 'Tracking';
            app.PlottingLabel = uilabel(app.UIFigure);
            app.PlottingLabel.Text = 'Plotting';
            app.SpottingLabel = uilabel(app.UIFigure);
            app.SpottingLabel.Text = 'Spotting';
            app.InputDataLabel = uilabel(app.UIFigure);
            app.InputDataLabel.Text = 'Input Data';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Icon = '012-shapes.png';
            app.Button_Input.Text = '';
            app.Button_Spot = uibutton(app.UIFigure, 'push');
            app.Button_Spot.ButtonPushedFcn = createCallbackFcn(app, @Button_SpotPushed, true);
            app.Button_Spot.Icon = '013-eye-open.png';
            app.Button_Spot.Text = '';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Icon = '001-dot-plot.png';
            app.Button_Plot.Text = '';
            app.Button_Track = uibutton(app.UIFigure, 'push');
            app.Button_Track.ButtonPushedFcn = createCallbackFcn(app, @Button_TrackPushed, true);
            app.Button_Track.Text = '➠';
            app.Button_Measure = uibutton(app.UIFigure, 'push');
            app.Button_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_MeasurePushed, true);
            app.Button_Measure.Text = '📏';
            app.SingleCellAnalysisWorkflowLabel = uilabel(app.UIFigure);
            app.SingleCellAnalysisWorkflowLabel.Text = 'Single Cell Analysis Workflow';
            app.KafriLabLabel = uilabel(app.UIFigure);
            app.KafriLabLabel.Text = '@Kafri-Lab 🔬';
            app.Button_Plot_2 = uibutton(app.UIFigure, 'push');
            app.Button_Plot_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Display_Pushed, true);
            app.Button_Plot_2.Icon = 'desktop-monitor.png';
            app.Button_Plot_2.Text = '';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.InputChannelEditField_2Label_2 = uilabel(app.UIFigure);
            app.InputChannelEditField_2Label_2.Text = '🎛🎚';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end